classdef TRFeedback_16Vibro_Demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VisualizeButton                 matlab.ui.control.StateButton
        StimulationDesignLabel          matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        CalibrationLabel                matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        PerceptionTab                   matlab.ui.container.Tab
        IndividualOrMultiple_Percep     matlab.ui.control.ListBox
        StartStimulationButton_Perception  matlab.ui.control.StateButton
        PerceptionThresholdLabel        matlab.ui.control.Label
        Perception_Ch_16                matlab.ui.control.StateButton
        Perception_amp_16               matlab.ui.control.Spinner
        Perception_Ch_15                matlab.ui.control.StateButton
        Perception_amp_15               matlab.ui.control.Spinner
        Perception_Ch_14                matlab.ui.control.StateButton
        Perception_amp_14               matlab.ui.control.Spinner
        Perception_Ch_13                matlab.ui.control.StateButton
        Perception_amp_13               matlab.ui.control.Spinner
        Perception_Ch_12                matlab.ui.control.StateButton
        Perception_amp_12               matlab.ui.control.Spinner
        Perception_Ch_11                matlab.ui.control.StateButton
        Perception_amp_11               matlab.ui.control.Spinner
        Perception_Ch_10                matlab.ui.control.StateButton
        Perception_amp_10               matlab.ui.control.Spinner
        Perception_Ch_9                 matlab.ui.control.StateButton
        Perception_amp_9                matlab.ui.control.Spinner
        Perception_Ch_8                 matlab.ui.control.StateButton
        Perception_amp_8                matlab.ui.control.Spinner
        Perception_Ch_7                 matlab.ui.control.StateButton
        Perception_amp_7                matlab.ui.control.Spinner
        Perception_Ch_6                 matlab.ui.control.StateButton
        Perception_amp_6                matlab.ui.control.Spinner
        Perception_Ch_5                 matlab.ui.control.StateButton
        Perception_amp_5                matlab.ui.control.Spinner
        Perception_Ch_4                 matlab.ui.control.StateButton
        Perception_amp_4                matlab.ui.control.Spinner
        Perception_Ch_3                 matlab.ui.control.StateButton
        Perception_amp_3                matlab.ui.control.Spinner
        Perception_Ch_2                 matlab.ui.control.StateButton
        Perception_amp_2                matlab.ui.control.Spinner
        Perception_Ch_1                 matlab.ui.control.StateButton
        Perception_amp_1                matlab.ui.control.Spinner
        MaximumTab                      matlab.ui.container.Tab
        IndividualOrMultiple_Max        matlab.ui.control.ListBox
        StartStimulationButton_Max      matlab.ui.control.StateButton
        MaximumThresholdLabel           matlab.ui.control.Label
        Maximum_Ch_16                   matlab.ui.control.StateButton
        Maximum_amp_16                  matlab.ui.control.Spinner
        Maximum_Ch_15                   matlab.ui.control.StateButton
        Maximum_amp_15                  matlab.ui.control.Spinner
        Maximum_Ch_14                   matlab.ui.control.StateButton
        Maximum_amp_14                  matlab.ui.control.Spinner
        Maximum_Ch_13                   matlab.ui.control.StateButton
        Maximum_amp_13                  matlab.ui.control.Spinner
        Maximum_Ch_12                   matlab.ui.control.StateButton
        Maximum_amp_12                  matlab.ui.control.Spinner
        Maximum_Ch_11                   matlab.ui.control.StateButton
        Maximum_amp_11                  matlab.ui.control.Spinner
        Maximum_Ch_10                   matlab.ui.control.StateButton
        Maximum_amp_10                  matlab.ui.control.Spinner
        Maximum_Ch_9                    matlab.ui.control.StateButton
        Maximum_amp_9                   matlab.ui.control.Spinner
        Maximum_Ch_8                    matlab.ui.control.StateButton
        Maximum_amp_8                   matlab.ui.control.Spinner
        Maximum_Ch_7                    matlab.ui.control.StateButton
        Maximum_amp_7                   matlab.ui.control.Spinner
        Maximum_Ch_6                    matlab.ui.control.StateButton
        Maximum_amp_6                   matlab.ui.control.Spinner
        Maximum_Ch_5                    matlab.ui.control.StateButton
        Maximum_amp_5                   matlab.ui.control.Spinner
        Maximum_Ch_4                    matlab.ui.control.StateButton
        Maximum_amp_4                   matlab.ui.control.Spinner
        Maximum_Ch_3                    matlab.ui.control.StateButton
        Maximum_amp_3                   matlab.ui.control.Spinner
        Maximum_Ch_2                    matlab.ui.control.StateButton
        Maximum_amp_2                   matlab.ui.control.Spinner
        Maximum_Ch_1                    matlab.ui.control.StateButton
        Maximum_amp_1                   matlab.ui.control.Spinner
        SettingTab                      matlab.ui.container.Tab
        ChoosecalibrationLabel          matlab.ui.control.Label
        ScaleLabel                      matlab.ui.control.Label
        TactorScale                     matlab.ui.control.Spinner
        CaliType                        matlab.ui.control.ListBox
        TabGroup                        matlab.ui.container.TabGroup
        PatternDesignTab                matlab.ui.container.Tab
        Pad_Grouping_Sum                matlab.ui.control.Label
        Duration_Sum                    matlab.ui.control.Label
        VerifyStimulationButton         matlab.ui.control.Button
        Amp_mult_Sum                    matlab.ui.control.Label
        Active_Tactors_Sum              matlab.ui.control.Label
        LoadButton_pattern              matlab.ui.control.Button
        SaveButton_pattern              matlab.ui.control.Button
        ResetButton_pattern             matlab.ui.control.Button
        StartStimulationButton_Pattern  matlab.ui.control.StateButton
        Inter_stim_pattern              matlab.ui.control.EditField
        Inter_stim_Label                matlab.ui.control.Label
        Duration_Pattern                matlab.ui.control.EditField
        Duration_pattern_label          matlab.ui.control.Label
        Amp_mult_pattern                matlab.ui.control.EditField
        Amp_mult_label                  matlab.ui.control.Label
        Pad_Grouping_Pattern            matlab.ui.control.EditField
        Pad_Grouping_label              matlab.ui.control.Label
        Active_Tactors_Pattern          matlab.ui.control.EditField
        Active_Tactors_label            matlab.ui.control.Label
        InterStim_Sum                   matlab.ui.control.Label
        IMUStimulationTab               matlab.ui.container.Tab
        IndividualOrMultiple_Percep_2   matlab.ui.control.ListBox
        StartStimulationButton_IMU      matlab.ui.control.StateButton
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ConnectLamp                     matlab.ui.control.Lamp
        ConnectSwitch                   matlab.ui.control.Switch
        MultichannelVibrotactileBeltStimulationLabel  matlab.ui.control.Label
        TRFeedbackDemoLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        bt_vib1 % Bluetooth object for vibro 1
        bt_vib2 % Bluetooth object for vibro 2
        connectSuccess1 = false;  % Flag to track connection status
        connectSuccess2 = false;  % Flag to track connection status
        ConnectedColor = [0.07 0.62 1.00] % Blue connected color map
        DisconnectedColor = [0.96 0.96 0.96];
        StimTimer
        StimTimer2
        VibroFigure % Description
        VibroCircles
        VibroCenters
        timer1
        timer2
        vib1_flag = false;
        vib2_flag = false;
    end
    
    properties (Access = private)
        imuScopeAcc1   % timescope object
        imuScopeAcc2
        imuScopeMean
    end
    
    methods (Access = private)
        
        function connectTRFBs(app)
            disp("Connecting to the TR-FB devices")
            btAddress1 = "842E1409E14E"; % Address for vibro 1
            btAddress2 = "8CF681F32E12"; % Address for vibro 2
            maxRetries = 5;  % Number of attempts
            % Try to connect to vibro 1
            if app.connectSuccess1 == false
                for i = 1:maxRetries
                    try
                        % Attempt to connect to the Bluetooth device
                        app.bt_vib1 = bluetooth(btAddress1);  % Channel 1 is usually the default
                        disp('TR-FB device 1: Connected successfully!');
                        app.connectSuccess1 = true;
                        fopen(app.bt_vib1);  % Open Bluetooth connection
                        break;
                    catch ME
                        disp(['Attempt ', num2str(i), ' failed: ', ME.message]);
                        disp('Retrying connection...');
                        pause(1); 
                    end
                end
            end
            % Try to connect to vibro 2
            if app.connectSuccess2 == false
                for i = 1:maxRetries
                    try
                        % Attempt to connect to the Bluetooth device
                        app.bt_vib2 = bluetooth(btAddress2);  % Channel 1 is usually the default
                        disp('TR-FB device 2: Connected successfully!');
                        app.connectSuccess2 = true;
                        fopen(app.bt_vib2);  % Open Bluetooth connection
                        break;
                    catch ME
                        disp(['Attempt ', num2str(i), ' failed: ', ME.message]);
                        disp('Retrying connection...');
                        pause(1); 
                    end
                end
            end
            % If not connected, give error msg
            if ~app.connectSuccess1 
                uialert(app.UIFigure, message, 'TR-FB device 1: Unable to establish Bluetooth connection - Try again', 'Icon', 'error');
            end
            if ~app.connectSuccess2
                uialert(app.UIFigure, message, 'TR-FB device 2: Unable to establish Bluetooth connection - Try again', 'Icon', 'error');
            end
            if app.connectSuccess1 == true && app.connectSuccess2 == true
                TRFeedback_MichelangeloSearch(app.bt_vib1, 0)
                TRFeedback_MichelangeloSearch(app.bt_vib2, 0)
                pause(0.5)
                
                app.ConnectLamp.Color = app.ConnectedColor;
                app.ConnectLamp.Visible = "on";
                
                % Turn the vibromotors on fast and off again
                vibromotors_intensities_1 = [20 20 20 20 20 20 20 20]; % For bt_vib1 (Ch 1-8)
                vibromotors_intensities_2 = [20 20 20 20 20 20 20 20]; % For bt_vib2 (Ch 9-16)
                TRFeedback_VibrationMotor_TriggerAll(app.bt_vib1, vibromotors_intensities_1);
                TRFeedback_VibrationMotor_TriggerAll(app.bt_vib2, vibromotors_intensities_2);
                pause(1);
                TurnOffStimulation(app);
                disp("Turning off stimulation");
            end
        end
        
        function disconnectTRFBs(app)
            disp("Disconnecting from the TR-FB devices");
            % Disconnect and clear the first Bluetooth device if it is connected
            if app.connectSuccess1 == true
                try
                    app.bt_vib1 = "";
                    disp("TR-FB device 1: Disconnected successfully.");
                catch ME
                    disp(['Error disconnecting TR-FB device 1: ', ME.message]);
                end
                app.connectSuccess1 = false;
            end
        
            % Disconnect and clear the second Bluetooth device if it is connected
            if app.connectSuccess2 == true
                try
                    app.bt_vib2 = "";
                    disp("TR-FB device 2: Disconnected successfully.");
                catch ME
                    disp(['Error disconnecting TR-FB device 2: ', ME.message]);
                end
                app.connectSuccess2 = false;
            end
        
            % Update UI indicator (lamp) to show disconnected status
            app.ConnectLamp.Color = app.DisconnectedColor; 
            app.ConnectLamp.Visible = "off";
            disp("Disconnection complete.");
        end
        
        
        function verifyStimulation(app)
            % Retrieve and process the values from the edit fields
            active_tactors = str2num(app.Active_Tactors_Pattern.Value); % Convert to numeric array
            pad_grouping = str2num(app.Pad_Grouping_Pattern.Value);
            ampmult = str2num(app.Amp_mult_pattern.Value);
            duration = str2num(app.Duration_Pattern.Value);
            interstim = str2num(app.Inter_stim_pattern.Value);
        
            % Define perception and maximum intensity levels for each tactor (Now 16 channels)
            perception_levels = [app.Perception_amp_1.Value, app.Perception_amp_2.Value, ...
                                 app.Perception_amp_3.Value, app.Perception_amp_4.Value, ...
                                 app.Perception_amp_5.Value, app.Perception_amp_6.Value, ...
                                 app.Perception_amp_7.Value, app.Perception_amp_8.Value, ...
                                 app.Perception_amp_9.Value, app.Perception_amp_10.Value, ...
                                 app.Perception_amp_11.Value, app.Perception_amp_12.Value, ...
                                 app.Perception_amp_13.Value, app.Perception_amp_14.Value, ...
                                 app.Perception_amp_15.Value, app.Perception_amp_16.Value];
        
            maximum_levels = [app.Maximum_amp_1.Value, app.Maximum_amp_2.Value, ...
                              app.Maximum_amp_3.Value, app.Maximum_amp_4.Value, ...
                              app.Maximum_amp_5.Value, app.Maximum_amp_6.Value, ...
                              app.Maximum_amp_7.Value, app.Maximum_amp_8.Value, ...
                              app.Maximum_amp_9.Value, app.Maximum_amp_10.Value, ...
                              app.Maximum_amp_11.Value, app.Maximum_amp_12.Value, ...
                              app.Maximum_amp_13.Value, app.Maximum_amp_14.Value, ...
                              app.Maximum_amp_15.Value, app.Maximum_amp_16.Value];
        
            error = 0;  % Initialize error flag
        
            % Check if the number of pads matches the sum of the sequence
            if length(active_tactors) ~= sum(pad_grouping)
                error = 1;
                uialert(app.UIFigure, ...
                    'Mismatch between number of selected tactors in Active tactors and summation of tactors in Tactor grouping', ...
                    'Match the applied pads', 'Icon', 'error');
            end
        
            % Check if the length of the sequence matches the Amp mult
            if length(pad_grouping) ~= length(ampmult)
                error = 1;
                uialert(app.UIFigure, ...
                    'Mismatch between entries in Tactor grouping and Amplitude multiplication', ...
                    'Match the applied sequences', 'Icon', 'error');
            end
        
            % Check if the length of Duration matches Interstim + 1
            if length(duration) ~= length(interstim) + 1
                error = 1;
                uialert(app.UIFigure, ...
                    'Mismatch between entries in Duration and Inter duration. The number of durations/tactor groupings must be one larger than inter-stimulation intervals', ...
                    'Match the applied tactor groupings', 'Icon', 'error');
            end
        
            % If no error was found, proceed to verify tactor amplitude
            if error == 0
                tactors_index = 0; % Initialize tactor index
                for i = 1:length(pad_grouping)
                    number_of_active_tactors = pad_grouping(i); % Number of tactors to activate for this sequence
                    tactors_to_activate = active_tactors(tactors_index + 1 : tactors_index + number_of_active_tactors);
                    tactors_index = tactors_index + number_of_active_tactors;
        
                    for tactor = 1:length(tactors_to_activate)
                        tactor_idx = tactors_to_activate(tactor); % Get the tactor index
                        tactor_amp = ampmult(i) * app.TactorScale.Value; % Calculate base amplitude
        
                        % Check if "Both" option is selected for intensity mapping
                        cali_type = app.CaliType.Value;
                        if cali_type == "Both"
                            % Make a linear mapping from perception to maximum level
                            tactor_amp = perception_levels(tactor_idx) + ...
                                (maximum_levels(tactor_idx) - perception_levels(tactor_idx)) * (tactor_amp / 100);
                        end
        
                        % Check if tactor_amp is out of the valid range (0-100)
                        if tactor_amp < 0 || tactor_amp > 100
                            error = 1;
                            message = sprintf('Tactor %d has an amplitude of %.2f, which is out of the valid range (0-100).', ...
                                              tactor_idx, tactor_amp);
                            uialert(app.UIFigure, message, 'Tactor Amplitude Out of Range', 'Icon', 'error');
                            return; % Exit early if any tactor fails the check
                        end
                    end
                end
            else
                app.VerifyStimulationButton.Text = "Verify Stimulation";
                app.VerifyStimulationButton.BackgroundColor = [0.96, 0.96, 0.96];
                app.StartStimulationButton_Pattern.Enable = "off";
            end
        
            % If no error was found, show success message
            if error == 0
                app.VerifyStimulationButton.Text = "Success";
                app.VerifyStimulationButton.BackgroundColor = [0.39, 0.83, 0.07];
                app.StartStimulationButton_Pattern.Enable = "on";
            end
        end
    end
    
    methods (Access = public)
        
        function showAmplitudeSpinnersPerception(app, buttonIdx)
            if app.IndividualOrMultiple_Percep.Value == "Individual"
                channel_name = sprintf('Perception_Ch_%d', buttonIdx);
                channel_object = app.(channel_name);
                value = channel_object.Value;

                % Loop through all channels and turn them off except the selected one
                for i = 1:16
                    other_channel_name = sprintf('Perception_Ch_%d', i);
                    other_amp_name = sprintf('Perception_amp_%d', i);
            
                    other_channel_object = app.(other_channel_name);
                    other_amp_object = app.(other_amp_name);
            
                    if i == buttonIdx && value == 1
                        % Keep the selected channel ON
                        other_channel_object.BackgroundColor = [0.30, 0.75, 0.93];
                        other_amp_object.Enable = "on";
                    else
                        % Turn other channels OFF
                        other_channel_object.Value = 0; % Ensure it's toggled off
                        other_channel_object.BackgroundColor = [0.96, 0.96, 0.96];
                        other_amp_object.Enable = "off";
                    end
                end
            else
                channel_name = sprintf('Perception_Ch_%d', buttonIdx);
                channel_object = app.(channel_name);
                
                amp_name = sprintf('Perception_amp_%d', buttonIdx);
                amp_object = app.(amp_name);
    
                value = channel_object.Value;
                if value == 1
                    channel_object.BackgroundColor = [0.30,0.75,0.93];
                    amp_object.Enable = "on";
                else
                    channel_object.BackgroundColor = [0.96,0.96,0.96];
                    amp_object.Enable = "off";
                end
            end
        end
        
        function showAmplitudeSpinnersMaximum(app, buttonIdx)
            if app.IndividualOrMultiple_Max.Value == "Individual"
                channel_name = sprintf('Maximum_Ch_%d', buttonIdx);
                channel_object = app.(channel_name);
                value = channel_object.Value;
            
                % Loop through all channels and turn them off except the selected one
                for i = 1:16
                    other_channel_name = sprintf('Maximum_Ch_%d', i);
                    other_amp_name = sprintf('Maximum_amp_%d', i);
            
                    other_channel_object = app.(other_channel_name);
                    other_amp_object = app.(other_amp_name);
            
                    if i == buttonIdx && value == 1
                        % Keep the selected channel ON
                        other_channel_object.BackgroundColor = [0.30, 0.75, 0.93];
                        other_amp_object.Enable = "on";
                    else
                        % Turn other channels OFF
                        other_channel_object.Value = 0; % Ensure it's toggled off
                        other_channel_object.BackgroundColor = [0.96, 0.96, 0.96];
                        other_amp_object.Enable = "off";
                    end
                end
            else
                channel_name = sprintf('Maximum_Ch_%d', buttonIdx);
                channel_object = app.(channel_name);
                
                amp_name = sprintf('Maximum_amp_%d', buttonIdx);
                amp_object = app.(amp_name);
    
                value = channel_object.Value;
                if value == 1
                    channel_object.BackgroundColor = [0.30,0.75,0.93];
                    amp_object.Enable = "on";
                else
                    channel_object.BackgroundColor = [0.96,0.96,0.96];
                    amp_object.Enable = "off";
                end
            end
        end
        
        function runPerceptionStimulation(app)
            % Initialize intensity arrays
            vibromotors_intensities_1 = zeros(1,8); % For bt_vib1 (Ch 1-8)
            vibromotors_intensities_2 = zeros(1,8); % For bt_vib2 (Ch 9-16)
        
            % Loop through all 16 channels
            for i = 1:16
                % Get channel state and amplitude
                channel_name = sprintf('Perception_Ch_%d', i);
                amp_name = sprintf('Perception_amp_%d', i);
        
                if app.(channel_name).Value == 1 % If active
                    intensity = app.(amp_name).Value;
                else
                    intensity = 0; % Turn off if inactive
                end
        
                % Assign to the correct Bluetooth device
                if i <= 8
                    vibromotors_intensities_1(i) = intensity;
                else
                    vibromotors_intensities_2(i-8) = intensity;
                end
            end
        
            % Send stimulation signals
            TRFeedback_VibrationMotor_TriggerAll(app.bt_vib1, vibromotors_intensities_1);
            TRFeedback_VibrationMotor_TriggerAll(app.bt_vib2, vibromotors_intensities_2);

            % Update visualization
            updateVibroSizes(app, vibromotors_intensities_1, vibromotors_intensities_2);
            
            %disp("Stimulation command sent.");
        end

        function runMaximumStimulation(app)
            % Initialize intensity arrays
            vibromotors_intensities_1 = zeros(1,8); % For bt_vib1 (Ch 1-8)
            vibromotors_intensities_2 = zeros(1,8); % For bt_vib2 (Ch 9-16)
        
            % Loop through all 16 channels
            for i = 1:16
                % Get channel state and amplitude
                channel_name = sprintf('Maximum_Ch_%d', i);
                amp_name = sprintf('Maximum_amp_%d', i);
        
                if app.(channel_name).Value == 1 % If active
                    intensity = app.(amp_name).Value;
                else
                    intensity = 0; % Turn off if inactive
                end
        
                % Assign to the correct Bluetooth device
                if i <= 8
                    vibromotors_intensities_1(i) = intensity;
                else
                    vibromotors_intensities_2(i-8) = intensity;
                end
            end
        
            % Send stimulation signals
            TRFeedback_VibrationMotor_TriggerAll(app.bt_vib1, vibromotors_intensities_1);
            TRFeedback_VibrationMotor_TriggerAll(app.bt_vib2, vibromotors_intensities_2);
            
            % Update visualization
            updateVibroSizes(app, vibromotors_intensities_1, vibromotors_intensities_2);
            
            %disp("Stimulation command sent.");
        end

        function TurnOffStimulation(app)
            vibromotors_intensities_1 = zeros(1,8); % For bt_vib1 (Ch 1-8)
            vibromotors_intensities_2 = zeros(1,8); % For bt_vib2 (Ch 9-16)
            TRFeedback_VibrationMotor_TriggerAll(app.bt_vib1, vibromotors_intensities_1);
            TRFeedback_VibrationMotor_TriggerAll(app.bt_vib2, vibromotors_intensities_2);
            disp("Turning off stimulation");
        end

        
        function updateVibroSizes(app, vibromotors_intensities_1, vibromotors_intensities_2)
            if app.VisualizeButton.Value == 1
                stim_intensity = [vibromotors_intensities_1, vibromotors_intensities_2];
                % Normalize intensity values (scaling the circle radius)
                minRadius = 0.15;
                maxRadius = 0.5;
                radii = minRadius + (maxRadius - minRadius) * (stim_intensity / 100);
            
                % Update circle sizes
                for i = 1:16
                    angle = linspace(0, 2*pi, 30); % Define circle shape
            
                    % Retrieve the FIXED center from stored values
                    xCenter = app.VibroCenters(1, i);
                    yCenter = app.VibroCenters(2, i);
            
                    % Update the circle without shifting its center
                    newX = xCenter + radii(i) * cos(angle);
                    newY = yCenter + radii(i) * sin(angle);
            
                    % Set new X and Y data
                    set(app.VibroCircles(i), 'XData', newX, 'YData', newY);
                end

                % Keep the limits fixed
                xlim([-3.5 3.5]);
                ylim([-2.5 2.5]);
            
                drawnow; % Ensure the figure updates
            end
        end

        function startIMURead(app)
           % Create two timers to read data asynchronously for each Bluetooth device
            % app.timer1 = timer(...
            %     'ExecutionMode', 'fixedRate', ...  % Repeated execution
            %     'Period', 0.01, ...  % Read every 10ms (100Hz)
            %     'TimerFcn', @(src, event)readIMUData(app, 1) ...  % Call function to read data from bt_vib1
            % );
            % 
            % app.timer2 = timer(...
            %     'ExecutionMode', 'fixedRate', ...  % Repeated execution
            %     'Period', 0.01, ...  % Read every 10ms (100Hz)
            %     'TimerFcn', @(src, event)readIMUData(app, 2) ...  % Call function to read data from bt_vib2
            % );
            % 
            % start(app.timer1);  % Start the first timer for bt_vib1
            % start(app.timer2);  % Start the second timer for bt_vib2
            
        end

        function readIMUData(app)
            read_vib1 = app.bt_vib1;
            read_vib2 = app.bt_vib2;
           
            if read_vib1.BytesAvailable >= 12
                app.vib1_flag = true;
                rawData1 = fread(read_vib1, read_vib1.BytesAvailable);  % Read available data
                imuData1 = rawData1';
        
                % Extract acceleration (assuming little-endian and 16-bit values)
                accX1 = typecast(uint8(imuData1(7:8)), 'int16');
                accY1 = typecast(uint8(imuData1(9:10)), 'int16');
                accZ1 = typecast(uint8(imuData1(11:12)), 'int16');
    
                % Push values to timescope
                if ~isempty(app.imuScopeAcc1)
                    app.imuScopeAcc1(accX1, accY1, accZ1);
                end
 
            end

            if read_vib2.BytesAvailable >= 12
                app.vib2_flag = true;
                rawData2 = fread(read_vib2, read_vib2.BytesAvailable);  % Read available data
                imuData2 = rawData2';
        
                % Extract gyroscope data
                accX2 = typecast(uint8(imuData2(7:8)), 'int16');
                accY2 = typecast(uint8(imuData2(9:10)), 'int16');
                accZ2 = typecast(uint8(imuData2(11:12)), 'int16');

                if ~isempty(app.imuScopeAcc2)
                    app.imuScopeAcc2(accX2, accY2, accZ2);
                end
            end
            if app.vib1_flag == true && app.vib2_flag == true
                avgAccX = mean([accX1, accX2]);
                avgAccY = mean([accY1, accY2]);
                avgAccZ = mean([accZ1, accZ2]);
                app.imuScopeMean(avgAccX, avgAccY, avgAccZ);
                app.vib1_flag = false;
                app.vib2_flag = false;
            end
        end


        function stopIMURead(app)
            % Stop the timers and close Bluetooth connections
            % stop(app.timer1);  % Stop the first timer
            % stop(app.timer2);  % Stop the second timer
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: ConnectSwitch
        function OnOffButtonPushed(app, event)
            if app.ConnectSwitch.Value == "On"
                % Connect
                connectTRFBs(app);
            else
                % Disconnect
                disconnectTRFBs(app);
            end
        end

        % Value changed function: Perception_Ch_1, Perception_Ch_10, 
        % ...and 14 other components
        function EnableChannelAmpPerception(app, event)
            button = event.Source;
            % Get the button index from its Tag
            buttonIdx = str2double(button.Text);
            showAmplitudeSpinnersPerception(app, buttonIdx);
        end

        % Value changed function: Maximum_Ch_1, Maximum_Ch_10, 
        % ...and 14 other components
        function EnableChannelAmpMaximum(app, event)
            button = event.Source;
            % Get the button index from its Tag
            buttonIdx = str2double(button.Text);
            showAmplitudeSpinnersMaximum(app, buttonIdx);
        end

        % Value changed function: StartStimulationButton_Perception
        function StartStimulationPerception(app, event)
            if app.StartStimulationButton_Perception.Value == 1
                disp("Starting Stimulation Timer...");
        
                % Create and start the timer if it does not already exist
                if isempty(app.StimTimer) || ~isvalid(app.StimTimer)
                    app.StimTimer = timer(...
                        'ExecutionMode', 'fixedRate', ... % Runs continuously at intervals
                        'Period', 0.1, ...  % Runs every 100ms (adjust as needed)
                        'TimerFcn', @(~,~) runPerceptionStimulation(app), ... % Calls function
                        'StopFcn', @(~,~) disp("Stimulation Timer Stopped.") ... % Display when stopped
                    );
                    start(app.StimTimer);
                end
            else
                TurnOffStimulation(app);
                % Stop and delete the timer when the button is turned off
                if ~isempty(app.StimTimer) && isvalid(app.StimTimer)
                    stop(app.StimTimer);
                    delete(app.StimTimer);
                    app.StimTimer = [];
                    disp("Stimulation Stopped.");
                end
            end
        end

        % Value changed function: StartStimulationButton_Max
        function StartStimulationMaxmimum(app, event)
            if app.StartStimulationButton_Max.Value == 1
                disp("Starting Stimulation Timer...");
                % Create and start the timer if it does not already exist
                if isempty(app.StimTimer2) || ~isvalid(app.StimTimer2)
                    app.StimTimer2 = timer(...
                        'ExecutionMode', 'fixedRate', ... % Runs continuously at intervals
                        'Period', 0.1, ...  % Runs every 100ms (adjust as needed)
                        'TimerFcn', @(~,~) runMaximumStimulation(app), ... % Calls function
                        'StopFcn', @(~,~) disp("Stimulation Timer Stopped.") ... % Display when stopped
                    );
                    start(app.StimTimer2);
                end
            else
                TurnOffStimulation(app);
                % Stop and delete the timer when the button is turned off
                if ~isempty(app.StimTimer2) && isvalid(app.StimTimer2)
                    stop(app.StimTimer2);
                    delete(app.StimTimer2);
                    app.StimTimer2 = [];
                    disp("Stimulation Stopped.");
                end
            end
        end

        % Button pushed function: VerifyStimulationButton
        function VerifyButtonPushed(app, event)
            verifyStimulation(app);
        end

        % Value changed function: Active_Tactors_Pattern
        function Active_Tactors_PatternValueChanged(app, event)
            % Get the value from the text edit field
            active_tactors = str2num(app.Active_Tactors_Pattern.Value);
            length_active_tactors = length(active_tactors);
            app.Active_Tactors_Sum.Text = "Length: " + num2str(length_active_tactors);

            % Check if any tactor is out of bounds (below 1 or above 16)
            if any(active_tactors < 1) || any(active_tactors > 16)
                uialert(app.UIFigure, 'Tactor numbers must be between 1 and 16.', 'Invalid Input', 'Icon', 'warning');
            end
            
            % Reset verify because some inputs changed
            app.VerifyStimulationButton.Text = "Verify Stimulation";
            app.VerifyStimulationButton.BackgroundColor = [0.96,0.96,0.96];
            app.StartStimulationButton_Pattern.Enable = "off";
        end

        % Value changed function: Pad_Grouping_Pattern
        function Pad_Grouping_PatternValueChanged(app, event)
            % Get the value from the text edit field
            input_num = str2num(app.Pad_Grouping_Pattern.Value); % Assuming .Value for the edit field
            length_of_input = length(input_num);
            sum_of_input = sum(input_num);
            app.Pad_Grouping_Sum.Text = "Length: " + num2str(length_of_input) + " | Sum: " + num2str(sum_of_input);
            
            % Check if any tactor is out of bounds (below 1 or above 16)
            if any(input_num < 1) || any(input_num > 16)
                uialert(app.UIFigure, 'Tactor grouping must be between 1 and 16.', 'Invalid Input', 'Icon', 'warning');
            end

            % Reset verify because some inputs changed
            app.VerifyStimulationButton.Text = "Verify Stimulation";
            app.VerifyStimulationButton.BackgroundColor = [0.96,0.96,0.96];
            app.StartStimulationButton_Pattern.Enable = "off";
        end

        % Value changed function: Amp_mult_pattern
        function Amp_mult_patternValueChanged(app, event)
            % Get the value from the text edit field
            input_num = str2num(app.Amp_mult_pattern.Value); % Assuming .Value for the edit field
            length_of_input = length(input_num);
            app.Amp_mult_Sum.Text = "Length: " + num2str(length_of_input);
            
            % Check if for out of bounds (below 0 or above 100)
            if any(input_num < 0) || any(input_num > 100)
                uialert(app.UIFigure, 'Tactor amplitude multiplication must be between 0 and 100.', 'Invalid Input', 'Icon', 'warning');
            end

            % Reset verify because some inputs changed
            app.VerifyStimulationButton.Text = "Verify Stimulation";
            app.VerifyStimulationButton.BackgroundColor = [0.96,0.96,0.96];
            app.StartStimulationButton_Pattern.Enable = "off";
            
        end

        % Value changed function: Duration_Pattern
        function Duration_PatternValueChanged(app, event)
            % Get the value from the text edit field
            input_num = str2num(app.Duration_Pattern.Value); % Assuming .Value for the edit field
            length_of_input = length(input_num);
            sum_of_input = sum(input_num);
            app.Duration_Sum.Text = "Length: " + num2str(length_of_input) + " | Sum: " + num2str(sum_of_input);
            
            if any(input_num < 0)
                uialert(app.UIFigure, 'Duration cannot be below 0.', 'Invalid Input', 'Icon', 'warning');
            end

            % Reset verify because some inputs changed
            app.VerifyStimulationButton.Text = "Verify Stimulation";
            app.VerifyStimulationButton.BackgroundColor = [0.96,0.96,0.96];
            app.StartStimulationButton_Pattern.Enable = "off";
        end

        % Value changed function: Inter_stim_pattern
        function Inter_stim_patternValueChanged(app, event)
            % Get the value from the text edit field
            input_num = str2num(app.Inter_stim_pattern.Value); % Assuming .Value for the edit field
            length_of_input = length(input_num);
            sum_of_input = sum(input_num);
            app.InterStim_Sum.Text = "Length: " + num2str(length_of_input) + " | Sum: " + num2str(sum_of_input);
            
            if any(input_num < 0)
                uialert(app.UIFigure, 'Inter duration cannot be below 0.', 'Invalid Input', 'Icon', 'warning');
            end

            % Reset verify because some inputs changed
            app.VerifyStimulationButton.Text = "Verify Stimulation";
            app.VerifyStimulationButton.BackgroundColor = [0.96,0.96,0.96];
            app.StartStimulationButton_Pattern.Enable = "off";
        end

        % Button pushed function: ResetButton_pattern
        function ResetButton_patternPushed(app, event)
            app.Active_Tactors_Pattern.Value = "1";
            app.Pad_Grouping_Pattern.Value = "ones(1,1)*1";
            app.Amp_mult_pattern.Value = "ones(1,1)*1";
            app.Duration_Pattern.Value = "ones(1,1)*1";
            app.Inter_stim_pattern.Value = "ones(1,1-1)*0";
            app.Active_Tactors_Sum.Text = "Length: 1";
            app.Pad_Grouping_Sum.Text = "Length: 1 | Sum: 1";
            app.Amp_mult_Sum.Text = "Length: 1 | Sum: 1";
            app.Duration_Sum.Text = "Length: 1 | Sum: 1";
            app.InterStim_Sum.Text = "Length: 0 | Sum: 0";
        end

        % Button pushed function: SaveButton_pattern
        function SaveButton_patternPushed(app, event)
            % Retrieve input values as strings from the app (without conversion to numeric)
            active_tactors = app.Active_Tactors_Pattern.Value;  % Active tactors
            pad_grouping = app.Pad_Grouping_Pattern.Value; % Grouping pattern
            amp_mult = app.Amp_mult_pattern.Value; % Amplitude multipliers
            durations = app.Duration_Pattern.Value; % Duration of stimulation
            inter_stim = app.Inter_stim_pattern.Value; % Inter-stimulation duration
            
            % Save the variables into a .mat file
            [file, path] = uiputfile('*.mat', 'Save Pattern Design As');
            if ischar(file)  % Ensure a file was selected
                save(fullfile(path, file), 'active_tactors', 'pad_grouping', 'amp_mult', 'durations', 'inter_stim');
                disp('Pattern saved successfully!');
            end
        end

        % Button pushed function: LoadButton_pattern
        function LoadButton_patternPushed(app, event)
            % Open a dialog to select the .mat file to load
            [file, path] = uigetfile('*.mat', 'Select Pattern Design File');
            if ischar(file)  % Ensure a file was selected
                % Load the data from the file
                loadedData = load(fullfile(path, file));
                
                % Assign the loaded values directly to the app's fields (preserving the original format)
                app.Active_Tactors_Pattern.Value = loadedData.active_tactors; % Active tactors
                app.Pad_Grouping_Pattern.Value = loadedData.pad_grouping; % Grouping pattern
                app.Amp_mult_pattern.Value = loadedData.amp_mult; % Amplitude multipliers
                app.Duration_Pattern.Value = loadedData.durations; % Duration of stimulation
                app.Inter_stim_pattern.Value = loadedData.inter_stim; % Inter-stimulation duration
                
                disp('Pattern loaded successfully!');
            end
            % Active the functions that calculate the sums
            Active_Tactors_PatternValueChanged(app);
            Pad_Grouping_PatternValueChanged(app);
            Amp_mult_patternValueChanged(app);
            Duration_PatternValueChanged(app);
            Inter_stim_patternValueChanged(app);
        end

        % Value changed function: StartStimulationButton_Pattern
        function StartStimulationPattern(app, event)
            disp("Starting Stimulation")
            app.StartStimulationButton_Pattern.Text = "Stop Stimulation";
            app.StartStimulationButton_Pattern.BackgroundColor = [0.39, 0.83, 0.07];
            
            stim_flag = app.StartStimulationButton_Pattern.Value;
            
            % Retrieve inputs from the app
            active_tactors = str2num(app.Active_Tactors_Pattern.Value);
            pad_grouping = str2num(app.Pad_Grouping_Pattern.Value);
            amp_mult = str2num(app.Amp_mult_pattern.Value);
            durations = str2num(app.Duration_Pattern.Value);
            inter_stim = str2num(app.Inter_stim_pattern.Value);
            
            % Define perception and maximum intensity levels for each tactor
            perception_levels = [app.Perception_amp_1.Value, app.Perception_amp_2.Value, ...
                                 app.Perception_amp_3.Value, app.Perception_amp_4.Value, ...
                                 app.Perception_amp_5.Value, app.Perception_amp_6.Value, ...
                                 app.Perception_amp_7.Value, app.Perception_amp_8.Value, ...
                                 app.Perception_amp_9.Value, app.Perception_amp_10.Value, ...
                                 app.Perception_amp_11.Value, app.Perception_amp_12.Value, ...
                                 app.Perception_amp_13.Value, app.Perception_amp_14.Value, ...
                                 app.Perception_amp_15.Value, app.Perception_amp_16.Value];
        
            maximum_levels = [app.Maximum_amp_1.Value, app.Maximum_amp_2.Value, ...
                              app.Maximum_amp_3.Value, app.Maximum_amp_4.Value, ...
                              app.Maximum_amp_5.Value, app.Maximum_amp_6.Value, ...
                              app.Maximum_amp_7.Value, app.Maximum_amp_8.Value, ...
                              app.Maximum_amp_9.Value, app.Maximum_amp_10.Value, ...
                              app.Maximum_amp_11.Value, app.Maximum_amp_12.Value, ...
                              app.Maximum_amp_13.Value, app.Maximum_amp_14.Value, ...
                              app.Maximum_amp_15.Value, app.Maximum_amp_16.Value];
            
            tactors_index = 0;
            for i = 1:length(pad_grouping)
                vibromotors_intensities_1 = zeros(1, 8); % For bt_vib1 (Ch 1-8)
                vibromotors_intensities_2 = zeros(1, 8); % For bt_vib2 (Ch 9-16)
                number_of_active_tactors = pad_grouping(i);
                tactors_to_activate = active_tactors(tactors_index + 1 : tactors_index + number_of_active_tactors);
                tactors_index = tactors_index + number_of_active_tactors;
                
                for tactor_idx = tactors_to_activate
                    tactor_amp = amp_mult(i) * app.TactorScale.Value;
                    Cali_type = app.CaliType.Value;
                    
                    switch Cali_type
                        case "1"
                            perception_level = perception_levels(tactor_idx);
                            maximum_level = maximum_levels(tactor_idx);
                            tactor_amp_cal = min(perception_level + (maximum_level - perception_level) * (tactor_amp / 100), maximum_level);
                        case "2"
                            tactor_amp_cal = tactor_amp;
                        case "3"
                            perception_level = perception_levels(tactor_idx);
                            tactor_amp_cal = perception_level + (100 - perception_level) * (tactor_amp / 100);
                        case "4"
                            maximum_level = maximum_levels(tactor_idx);
                            tactor_amp_cal = maximum_level * (tactor_amp / 100);
                    end
                    
                    if tactor_idx <= 8
                        vibromotors_intensities_1(tactor_idx) = tactor_amp_cal;
                    else
                        vibromotors_intensities_2(tactor_idx - 8) = tactor_amp_cal;
                    end
                end

                % Update visualization
                updateVibroSizes(app, vibromotors_intensities_1, vibromotors_intensities_2);
                
                startTime = tic;
                while toc(startTime) < durations(i)
                    if ~app.StartStimulationButton_Pattern.Value
                        disp("Stimulation Stopped")
                        break;
                    end
                    %TRFeedback_VibrationMotor_TriggerAll(app.bt_vib1, vibromotors_intensities_1);
                    %TRFeedback_VibrationMotor_TriggerAll(app.bt_vib2, vibromotors_intensities_2);
                    pause(0.0001);  % Short pause to check for interruption more frequently
                end

                % Stop all motors after the loop or if the stimulation was interrupted
                %TRFeedback_VibrationMotor_TriggerAll(app.bt_vib1, zeros(1, 8));
                %TRFeedback_VibrationMotor_TriggerAll(app.bt_vib2, zeros(1, 8));

                % Update visualization
                updateVibroSizes(app, vibromotors_intensities_1, vibromotors_intensities_2);
        
                if i ~= length(pad_grouping) && inter_stim(i) > 0
                    disp("InterStim")
                    pause(inter_stim(i));
                end
            end
            
            disp("Stimulation Over")
            app.StartStimulationButton_Pattern.Value = false;
            app.StartStimulationButton_Pattern.Text = "Start Stimulation";
            app.StartStimulationButton_Pattern.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            for i = 1:16
                % Reset Percept values to 0
                ampName = sprintf('Perception_amp_%d', i);
                perceptionAmp = app.(ampName);
                perceptionAmp.Value = 0;
                
                chName = sprintf('Perception_Ch_%d', i);
                perceptionCh = app.(chName);
                perceptionCh.Value = 0;
                showAmplitudeSpinnersPerception(app, i);


                % Reset Max values to 100
                ampName = sprintf('Maximum_amp_%d', i);
                MaximumAmp = app.(ampName);
                MaximumAmp.Value = 100;
                
                chName = sprintf('Maximum_Ch_%d', i);
                MaximumCh = app.(chName);
                MaximumCh.Value = 0;
                showAmplitudeSpinnersMaximum(app, i);
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Collect calibration data
            calibrationData.Percept = [app.Perception_amp_1.Value, app.Perception_amp_2.Value, ...
                                       app.Perception_amp_3.Value, app.Perception_amp_4.Value, ...
                                       app.Perception_amp_5.Value, app.Perception_amp_6.Value, ...
                                       app.Perception_amp_7.Value, app.Perception_amp_8.Value, ...
                                       app.Perception_amp_9.Value, app.Perception_amp_10.Value, ...
                                       app.Perception_amp_11.Value, app.Perception_amp_12.Value, ...
                                       app.Perception_amp_13.Value, app.Perception_amp_14.Value, ...
                                       app.Perception_amp_15.Value, app.Perception_amp_16.Value];
            
            calibrationData.Max = [app.Maximum_amp_1.Value, app.Maximum_amp_2.Value, ...
                                   app.Maximum_amp_3.Value, app.Maximum_amp_4.Value, ...
                                   app.Maximum_amp_5.Value, app.Maximum_amp_6.Value, ...
                                   app.Maximum_amp_7.Value, app.Maximum_amp_8.Value, ...
                                   app.Maximum_amp_9.Value, app.Maximum_amp_10.Value, ...
                                   app.Maximum_amp_11.Value, app.Maximum_amp_12.Value, ...
                                   app.Maximum_amp_13.Value, app.Maximum_amp_14.Value, ...
                                   app.Maximum_amp_15.Value, app.Maximum_amp_16.Value];
            
            % Save the calibration data to a .mat file
            [file, path] = uiputfile('*.mat', 'Save Calibration Data');
            if isequal(file, 0)
                disp('User canceled the save operation.');
            else
                save(fullfile(path, file), 'calibrationData');
                disp('Calibration data saved successfully.');
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Load calibration data from a .mat file
            [file, path] = uigetfile('*.mat', 'Load Calibration Data');
            if isequal(file, 0)
                disp('User canceled the load operation.');
            else
                loadedData = load(fullfile(path, file), 'calibrationData');
                disp(loadedData)
                calibrationData = loadedData.calibrationData;
                disp(calibrationData)
                
                % Set the calibration values to the app's UI components
                for i = 1:16
                    ampName = sprintf('Perception_amp_%d', i);
                    perceptionAmp = app.(ampName);
                    perceptionAmp.Value = calibrationData.Percept(i);
                    
                    ampName = sprintf('Maximum_amp_%d', i);
                    MaximumAmp = app.(ampName);
                    MaximumAmp.Value = calibrationData.Max(i);
                end
                
                disp('Calibration data loaded successfully.');
            end
        end

        % Value changed function: StartStimulationButton_IMU
        function IMUStimulationTabButtonDown(app, event)
            if app.StartStimulationButton_IMU.Value == true
                disp("Starting IMU Stimulation Setup")
                app.imuScopeAcc1 = timescope(...
                    'NumInputPorts', 3, ...
                    'Name', 'IMU Acceleration 1', ...
                    'SampleRate', 100, ...  
                    'TimeSpanSource', 'Property', ...
                    'TimeSpan', 2, ...
                    'YLimits', [-1100, 1100], ...
                    'ShowGrid', true, ...
                    'ChannelNames', {'AccX1', 'AccY1', 'AccZ1'});

                app.imuScopeAcc2 = timescope(...
                    'NumInputPorts', 3, ...
                    'Name', 'IMU Acceleration 2', ...
                    'SampleRate', 100, ...  
                    'TimeSpanSource', 'Property', ...
                    'TimeSpan', 2, ...
                    'YLimits', [-1100, 1100], ...
                    'ShowGrid', true, ...
                    'ChannelNames', {'AccX2', 'AccY2', 'AccZ2'});

                app.imuScopeMean = timescope(...
                    'NumInputPorts', 3, ...
                    'Name', 'IMU Acceleration Mean', ...
                    'SampleRate', 100, ...  
                    'TimeSpanSource', 'Property', ...
                    'TimeSpan', 2, ...
                    'YLimits', [-1100, 1100], ...
                    'ShowGrid', true, ...
                    'ChannelNames', {'MeanX', 'MeanY', 'MeanZ'});
                
                TRFeedback_Stream(1,app.bt_vib1)
                TRFeedback_Stream(1,app.bt_vib2)

                while app.StartStimulationButton_IMU.Value == true
                    readIMUData(app); %Start reading data
                    pause(0.001)
                end
            else
                disp("Stopping IMU Stimulation Setup")
                TRFeedback_Stream(0,app.bt_vib1); %Stop reading data
                TRFeedback_Stream(0,app.bt_vib2); %Stop reading data
            end
        end

        % Value changed function: VisualizeButton
        function VisualizeVibros(app, event)
           if app.VisualizeButton.Value == 1
                % Define oval shape parameters
                a = 3; % Semi-major axis (horizontal)
                b = 2; % Semi-minor axis (vertical)
                theta = linspace(0, 2*pi, 17); % 16 points evenly spaced around oval
                theta(end) = []; % Remove last point to avoid duplicate
                x = a * cos(theta);
                y = b * sin(theta);
        
                % Create figure if it doesn’t exist
                if ~isfield(app, 'VibroFigure') || ~isvalid(app.VibroFigure)
                    app.VibroFigure = figure('Name', 'Vibrotactile Stimulation Visualization');
                    hold on;

                    % Store the fixed centers for each circle
                    app.VibroCenters = [x; y]; % 2x16 matrix: row 1 is x, row 2 is y
        
                    % Store circle handles
                    app.VibroCircles = gobjects(1, 16);
                    r = 0.15; % Initial radius for circles
        
                    % Draw 16 circles
                    for i = 1:16
                        angle = linspace(0, 2*pi, 30); % Smooth circle
                        app.VibroCircles(i) = plot(x(i) + r*cos(angle), y(i) + r*sin(angle), 'b', 'LineWidth', 2);
                        text(x(i), y(i), sprintf('%d', i), 'HorizontalAlignment', 'center', ...
                            'VerticalAlignment', 'middle', 'FontSize', 12, 'FontWeight', 'bold', 'Color', 'k');
                    end
        
                    % Formatting
                    xlim([-3.5 3.5]);
                    ylim([-2.5 2.5]);
                    axis equal;
                    title('Vibrotactile Stimulation Visualization');
                    xlabel('X Position');
                    ylabel('Y Position');
                    grid on;
                end
            else
                % Close the figure if the button is toggled off
                try
                    close(app.VibroFigure);
                    app.VibroFigure = []; % Clear the figure handle
                catch ME

                end
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TRFeedbackDemoLabel = uilabel(app.UIFigure);
            app.TRFeedbackDemoLabel.Text = 'TR-Feedback Demo';
            app.MultichannelVibrotactileBeltStimulationLabel = uilabel(app.UIFigure);
            app.MultichannelVibrotactileBeltStimulationLabel.Text = 'Multichannel Vibrotactile Belt Stimulation';
            app.ConnectSwitch = uiswitch(app.UIFigure, 'slider');
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @OnOffButtonPushed, true);
            app.ConnectLamp = uilamp(app.UIFigure);
            app.ConnectLamp.Visible = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PatternDesignTab = uitab(app.TabGroup);
            app.PatternDesignTab.Title = 'Pattern Design';
            app.InterStim_Sum = uilabel(app.PatternDesignTab);
            app.InterStim_Sum.Text = 'Length: 0 | Sum: 0';
            app.Active_Tactors_label = uilabel(app.PatternDesignTab);
            app.Active_Tactors_label.Text = 'Active tactors:';
            app.Active_Tactors_Pattern = uieditfield(app.PatternDesignTab, 'text');
            app.Active_Tactors_Pattern.ValueChangedFcn = createCallbackFcn(app, @Active_Tactors_PatternValueChanged, true);
            app.Active_Tactors_Pattern.Value = '1';
            app.Pad_Grouping_label = uilabel(app.PatternDesignTab);
            app.Pad_Grouping_label.Text = 'Tactor grouping:';
            app.Pad_Grouping_Pattern = uieditfield(app.PatternDesignTab, 'text');
            app.Pad_Grouping_Pattern.ValueChangedFcn = createCallbackFcn(app, @Pad_Grouping_PatternValueChanged, true);
            app.Pad_Grouping_Pattern.Value = 'ones(1,1)*1';
            app.Amp_mult_label = uilabel(app.PatternDesignTab);
            app.Amp_mult_label.Text = 'Amplitude multiplication:';
            app.Amp_mult_pattern = uieditfield(app.PatternDesignTab, 'text');
            app.Amp_mult_pattern.ValueChangedFcn = createCallbackFcn(app, @Amp_mult_patternValueChanged, true);
            app.Amp_mult_pattern.Value = 'ones(1,1)*1';
            app.Duration_pattern_label = uilabel(app.PatternDesignTab);
            app.Duration_pattern_label.Text = 'Duration:';
            app.Duration_Pattern = uieditfield(app.PatternDesignTab, 'text');
            app.Duration_Pattern.ValueChangedFcn = createCallbackFcn(app, @Duration_PatternValueChanged, true);
            app.Duration_Pattern.Value = 'ones(1,1)*1';
            app.Inter_stim_Label = uilabel(app.PatternDesignTab);
            app.Inter_stim_Label.Text = 'Inter stimulation:';
            app.Inter_stim_pattern = uieditfield(app.PatternDesignTab, 'text');
            app.Inter_stim_pattern.ValueChangedFcn = createCallbackFcn(app, @Inter_stim_patternValueChanged, true);
            app.Inter_stim_pattern.Value = 'ones(1,1-1)*0';
            app.StartStimulationButton_Pattern = uibutton(app.PatternDesignTab, 'state');
            app.StartStimulationButton_Pattern.ValueChangedFcn = createCallbackFcn(app, @StartStimulationPattern, true);
            app.StartStimulationButton_Pattern.Enable = 'off';
            app.StartStimulationButton_Pattern.Text = {'Start '; 'Stimulation'};
            app.ResetButton_pattern = uibutton(app.PatternDesignTab, 'push');
            app.ResetButton_pattern.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_patternPushed, true);
            app.ResetButton_pattern.Text = 'Reset';
            app.SaveButton_pattern = uibutton(app.PatternDesignTab, 'push');
            app.SaveButton_pattern.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_patternPushed, true);
            app.SaveButton_pattern.Text = 'Save';
            app.LoadButton_pattern = uibutton(app.PatternDesignTab, 'push');
            app.LoadButton_pattern.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_patternPushed, true);
            app.LoadButton_pattern.Text = 'Load';
            app.Active_Tactors_Sum = uilabel(app.PatternDesignTab);
            app.Active_Tactors_Sum.Text = 'Length: 1';
            app.Amp_mult_Sum = uilabel(app.PatternDesignTab);
            app.Amp_mult_Sum.Text = 'Length: 1';
            app.VerifyStimulationButton = uibutton(app.PatternDesignTab, 'push');
            app.VerifyStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyStimulationButton.Text = {'Verify'; 'Stimulation'};
            app.Duration_Sum = uilabel(app.PatternDesignTab);
            app.Duration_Sum.Text = 'Length: 1 | Sum: 1';
            app.Pad_Grouping_Sum = uilabel(app.PatternDesignTab);
            app.Pad_Grouping_Sum.Text = 'Length: 1 | Sum: 1';
            app.IMUStimulationTab = uitab(app.TabGroup);
            app.IMUStimulationTab.Title = 'IMU Stimulation';
            app.UIAxes = uiaxes(app.IMUStimulationTab);
            title(app.UIAxes, 'IMU 1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.IMUStimulationTab);
            title(app.UIAxes_2, 'IMU 2')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.StartStimulationButton_IMU = uibutton(app.IMUStimulationTab, 'state');
            app.StartStimulationButton_IMU.ValueChangedFcn = createCallbackFcn(app, @IMUStimulationTabButtonDown, true);
            app.StartStimulationButton_IMU.Text = {'Start '; 'Stimulation'};
            app.IndividualOrMultiple_Percep_2 = uilistbox(app.IMUStimulationTab);
            app.IndividualOrMultiple_Percep_2.Items = {'Only IMU 1', 'Only IMU 2', 'Mean of both'};
            app.IndividualOrMultiple_Percep_2.Value = 'Only IMU 1';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.PerceptionTab = uitab(app.TabGroup2);
            app.PerceptionTab.Title = 'Perception';
            app.Perception_amp_1 = uispinner(app.PerceptionTab);
            app.Perception_amp_1.Limits = [0 100];
            app.Perception_amp_1.Enable = 'off';
            app.Perception_Ch_1 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_1.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_1.Text = '1';
            app.Perception_amp_2 = uispinner(app.PerceptionTab);
            app.Perception_amp_2.Limits = [0 100];
            app.Perception_amp_2.Enable = 'off';
            app.Perception_Ch_2 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_2.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_2.Text = '2';
            app.Perception_amp_3 = uispinner(app.PerceptionTab);
            app.Perception_amp_3.Limits = [0 100];
            app.Perception_amp_3.Enable = 'off';
            app.Perception_Ch_3 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_3.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_3.Text = '3';
            app.Perception_amp_4 = uispinner(app.PerceptionTab);
            app.Perception_amp_4.Limits = [0 100];
            app.Perception_amp_4.Enable = 'off';
            app.Perception_Ch_4 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_4.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_4.Text = '4';
            app.Perception_amp_5 = uispinner(app.PerceptionTab);
            app.Perception_amp_5.Limits = [0 100];
            app.Perception_amp_5.Enable = 'off';
            app.Perception_Ch_5 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_5.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_5.Tag = '5';
            app.Perception_Ch_5.Text = '5';
            app.Perception_amp_6 = uispinner(app.PerceptionTab);
            app.Perception_amp_6.Limits = [0 100];
            app.Perception_amp_6.Enable = 'off';
            app.Perception_Ch_6 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_6.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_6.Tag = '6';
            app.Perception_Ch_6.Text = '6';
            app.Perception_amp_7 = uispinner(app.PerceptionTab);
            app.Perception_amp_7.Limits = [0 100];
            app.Perception_amp_7.Enable = 'off';
            app.Perception_Ch_7 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_7.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_7.Tag = '7';
            app.Perception_Ch_7.Text = '7';
            app.Perception_amp_8 = uispinner(app.PerceptionTab);
            app.Perception_amp_8.Limits = [0 100];
            app.Perception_amp_8.Enable = 'off';
            app.Perception_Ch_8 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_8.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_8.Tag = '8';
            app.Perception_Ch_8.Text = '8';
            app.Perception_amp_9 = uispinner(app.PerceptionTab);
            app.Perception_amp_9.Limits = [0 100];
            app.Perception_amp_9.Enable = 'off';
            app.Perception_Ch_9 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_9.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_9.Tag = '9';
            app.Perception_Ch_9.Text = '9';
            app.Perception_amp_10 = uispinner(app.PerceptionTab);
            app.Perception_amp_10.Limits = [0 100];
            app.Perception_amp_10.Enable = 'off';
            app.Perception_Ch_10 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_10.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_10.Tag = '10';
            app.Perception_Ch_10.Text = '10';
            app.Perception_amp_11 = uispinner(app.PerceptionTab);
            app.Perception_amp_11.Limits = [0 100];
            app.Perception_amp_11.Enable = 'off';
            app.Perception_Ch_11 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_11.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_11.Tag = '11';
            app.Perception_Ch_11.Text = '11';
            app.Perception_amp_12 = uispinner(app.PerceptionTab);
            app.Perception_amp_12.Limits = [0 100];
            app.Perception_amp_12.Enable = 'off';
            app.Perception_Ch_12 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_12.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_12.Tag = '12';
            app.Perception_Ch_12.Text = '12';
            app.Perception_amp_13 = uispinner(app.PerceptionTab);
            app.Perception_amp_13.Limits = [0 100];
            app.Perception_amp_13.Enable = 'off';
            app.Perception_Ch_13 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_13.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_13.Tag = '13';
            app.Perception_Ch_13.Text = '13';
            app.Perception_amp_14 = uispinner(app.PerceptionTab);
            app.Perception_amp_14.Limits = [0 100];
            app.Perception_amp_14.Enable = 'off';
            app.Perception_Ch_14 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_14.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_14.Tag = '14';
            app.Perception_Ch_14.Text = '14';
            app.Perception_amp_15 = uispinner(app.PerceptionTab);
            app.Perception_amp_15.Limits = [0 100];
            app.Perception_amp_15.Enable = 'off';
            app.Perception_Ch_15 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_15.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_15.Tag = '15';
            app.Perception_Ch_15.Text = '15';
            app.Perception_amp_16 = uispinner(app.PerceptionTab);
            app.Perception_amp_16.Limits = [0 100];
            app.Perception_amp_16.Enable = 'off';
            app.Perception_Ch_16 = uibutton(app.PerceptionTab, 'state');
            app.Perception_Ch_16.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpPerception, true);
            app.Perception_Ch_16.Tag = '16';
            app.Perception_Ch_16.Text = '16';
            app.PerceptionThresholdLabel = uilabel(app.PerceptionTab);
            app.PerceptionThresholdLabel.Text = 'Perception Threshold';
            app.StartStimulationButton_Perception = uibutton(app.PerceptionTab, 'state');
            app.StartStimulationButton_Perception.ValueChangedFcn = createCallbackFcn(app, @StartStimulationPerception, true);
            app.StartStimulationButton_Perception.Text = {'Start '; 'Stimulation'};
            app.IndividualOrMultiple_Percep = uilistbox(app.PerceptionTab);
            app.IndividualOrMultiple_Percep.Items = {'Individual', 'Multiple'};
            app.IndividualOrMultiple_Percep.Value = 'Individual';
            app.MaximumTab = uitab(app.TabGroup2);
            app.MaximumTab.Title = 'Maximum';
            app.Maximum_amp_1 = uispinner(app.MaximumTab);
            app.Maximum_amp_1.Limits = [0 100];
            app.Maximum_amp_1.Enable = 'off';
            app.Maximum_amp_1.Value = 100;
            app.Maximum_Ch_1 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_1.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_1.Text = '1';
            app.Maximum_amp_2 = uispinner(app.MaximumTab);
            app.Maximum_amp_2.Limits = [0 100];
            app.Maximum_amp_2.Enable = 'off';
            app.Maximum_amp_2.Value = 100;
            app.Maximum_Ch_2 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_2.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_2.Text = '2';
            app.Maximum_amp_3 = uispinner(app.MaximumTab);
            app.Maximum_amp_3.Limits = [0 100];
            app.Maximum_amp_3.Enable = 'off';
            app.Maximum_amp_3.Value = 100;
            app.Maximum_Ch_3 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_3.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_3.Text = '3';
            app.Maximum_amp_4 = uispinner(app.MaximumTab);
            app.Maximum_amp_4.Limits = [0 100];
            app.Maximum_amp_4.Enable = 'off';
            app.Maximum_amp_4.Value = 100;
            app.Maximum_Ch_4 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_4.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_4.Text = '4';
            app.Maximum_amp_5 = uispinner(app.MaximumTab);
            app.Maximum_amp_5.Limits = [0 100];
            app.Maximum_amp_5.Enable = 'off';
            app.Maximum_amp_5.Value = 100;
            app.Maximum_Ch_5 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_5.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_5.Tag = '5';
            app.Maximum_Ch_5.Text = '5';
            app.Maximum_amp_6 = uispinner(app.MaximumTab);
            app.Maximum_amp_6.Limits = [0 100];
            app.Maximum_amp_6.Enable = 'off';
            app.Maximum_amp_6.Value = 100;
            app.Maximum_Ch_6 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_6.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_6.Tag = '6';
            app.Maximum_Ch_6.Text = '6';
            app.Maximum_amp_7 = uispinner(app.MaximumTab);
            app.Maximum_amp_7.Limits = [0 100];
            app.Maximum_amp_7.Enable = 'off';
            app.Maximum_amp_7.Value = 100;
            app.Maximum_Ch_7 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_7.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_7.Tag = '7';
            app.Maximum_Ch_7.Text = '7';
            app.Maximum_amp_8 = uispinner(app.MaximumTab);
            app.Maximum_amp_8.Limits = [0 100];
            app.Maximum_amp_8.Enable = 'off';
            app.Maximum_amp_8.Value = 100;
            app.Maximum_Ch_8 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_8.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_8.Tag = '8';
            app.Maximum_Ch_8.Text = '8';
            app.Maximum_amp_9 = uispinner(app.MaximumTab);
            app.Maximum_amp_9.Limits = [0 100];
            app.Maximum_amp_9.Enable = 'off';
            app.Maximum_amp_9.Value = 100;
            app.Maximum_Ch_9 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_9.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_9.Tag = '9';
            app.Maximum_Ch_9.Text = '9';
            app.Maximum_amp_10 = uispinner(app.MaximumTab);
            app.Maximum_amp_10.Limits = [0 100];
            app.Maximum_amp_10.Enable = 'off';
            app.Maximum_amp_10.Value = 100;
            app.Maximum_Ch_10 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_10.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_10.Tag = '10';
            app.Maximum_Ch_10.Text = '10';
            app.Maximum_amp_11 = uispinner(app.MaximumTab);
            app.Maximum_amp_11.Limits = [0 100];
            app.Maximum_amp_11.Enable = 'off';
            app.Maximum_amp_11.Value = 100;
            app.Maximum_Ch_11 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_11.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_11.Tag = '11';
            app.Maximum_Ch_11.Text = '11';
            app.Maximum_amp_12 = uispinner(app.MaximumTab);
            app.Maximum_amp_12.Limits = [0 100];
            app.Maximum_amp_12.Enable = 'off';
            app.Maximum_amp_12.Value = 100;
            app.Maximum_Ch_12 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_12.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_12.Tag = '12';
            app.Maximum_Ch_12.Text = '12';
            app.Maximum_amp_13 = uispinner(app.MaximumTab);
            app.Maximum_amp_13.Limits = [0 100];
            app.Maximum_amp_13.Enable = 'off';
            app.Maximum_amp_13.Value = 100;
            app.Maximum_Ch_13 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_13.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_13.Tag = '13';
            app.Maximum_Ch_13.Text = '13';
            app.Maximum_amp_14 = uispinner(app.MaximumTab);
            app.Maximum_amp_14.Limits = [0 100];
            app.Maximum_amp_14.Enable = 'off';
            app.Maximum_amp_14.Value = 100;
            app.Maximum_Ch_14 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_14.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_14.Tag = '14';
            app.Maximum_Ch_14.Text = '14';
            app.Maximum_amp_15 = uispinner(app.MaximumTab);
            app.Maximum_amp_15.Limits = [0 100];
            app.Maximum_amp_15.Enable = 'off';
            app.Maximum_amp_15.Value = 100;
            app.Maximum_Ch_15 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_15.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_15.Tag = '15';
            app.Maximum_Ch_15.Text = '15';
            app.Maximum_amp_16 = uispinner(app.MaximumTab);
            app.Maximum_amp_16.Limits = [0 100];
            app.Maximum_amp_16.Enable = 'off';
            app.Maximum_amp_16.Value = 100;
            app.Maximum_Ch_16 = uibutton(app.MaximumTab, 'state');
            app.Maximum_Ch_16.ValueChangedFcn = createCallbackFcn(app, @EnableChannelAmpMaximum, true);
            app.Maximum_Ch_16.Tag = '16';
            app.Maximum_Ch_16.Text = '16';
            app.MaximumThresholdLabel = uilabel(app.MaximumTab);
            app.MaximumThresholdLabel.Text = 'Maximum Threshold';
            app.StartStimulationButton_Max = uibutton(app.MaximumTab, 'state');
            app.StartStimulationButton_Max.ValueChangedFcn = createCallbackFcn(app, @StartStimulationMaxmimum, true);
            app.StartStimulationButton_Max.Text = {'Start '; 'Stimulation'};
            app.IndividualOrMultiple_Max = uilistbox(app.MaximumTab);
            app.IndividualOrMultiple_Max.Items = {'Individual', 'Multiple'};
            app.IndividualOrMultiple_Max.Value = 'Individual';
            app.SettingTab = uitab(app.TabGroup2);
            app.SettingTab.Title = 'Setting';
            app.CaliType = uilistbox(app.SettingTab);
            app.CaliType.Items = {'Both', 'No Calibration', 'Only Perception', 'Only Maximum'};
            app.CaliType.ItemsData = {'1', '2', '3', '4'};
            app.CaliType.Value = '1';
            app.TactorScale = uispinner(app.SettingTab);
            app.TactorScale.Step = 0.1;
            app.TactorScale.Limits = [1 Inf];
            app.TactorScale.Value = 1;
            app.ScaleLabel = uilabel(app.SettingTab);
            app.ScaleLabel.Text = 'Scale';
            app.ChoosecalibrationLabel = uilabel(app.SettingTab);
            app.ChoosecalibrationLabel.Text = 'Choose calibration';
            app.CalibrationLabel = uilabel(app.UIFigure);
            app.CalibrationLabel.Text = 'Calibration';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StimulationDesignLabel = uilabel(app.UIFigure);
            app.StimulationDesignLabel.Text = 'Stimulation Design';
            app.VisualizeButton = uibutton(app.UIFigure, 'state');
            app.VisualizeButton.ValueChangedFcn = createCallbackFcn(app, @VisualizeVibros, true);
            app.VisualizeButton.Text = 'Visualize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TRFeedback_16Vibro_Demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end