classdef st7test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        STARTButton     matlab.ui.control.StateButton
        Spinner         matlab.ui.control.Spinner
        ConnectButton   matlab.ui.control.StateButton
        ST7GruppeLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        bt_vib1 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ConnectButton
        function ConnectBLE(app, event)
            disp("Connecting to the TR-FB devices")
            btAddress1 = "842E1409E14E"; % Address for vibro 1
            app.bt_vib1 = bluetooth(btAddress1);

            %%
        end

        % Value changed function: STARTButton
        function startvibration(app, event)
            value = app.STARTButton.Value;

            vib1 = app.Spinner.Value;
            intensities = [vib1, 0,0,0,0,0,0,0];
            
            if value == true
                TRFeedback_VibrationMotor_TriggerAll(app.bt_vib1, intensities)
            else
                intensities = [0,0,0,0,0,0,0,0];
                TRFeedback_VibrationMotor_TriggerAll(app.bt_vib1, intensities)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ST7GruppeLabel = uilabel(app.UIFigure);
            app.ST7GruppeLabel.Text = 'ST7 Gruppe';
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectBLE, true);
            app.ConnectButton.Text = 'Connect';
            app.Spinner = uispinner(app.UIFigure);
            app.STARTButton = uibutton(app.UIFigure, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @startvibration, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = st7test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end